classdef SCSfallDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ExportFallDataButton   matlab.ui.control.Button
        StableLabel            matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        StartMonitoringButton  matlab.ui.control.Button
        FALLDETECTIONLabel     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Timer
        Data 
        FallSound              
        FallSoundFs 
        
    end

    
    methods (Access = private)
        
       function updateGraph(app)
            % Generate new data point
            newValue = rand();
            
            % Append new value to the data
            app.Data = [app.Data, newValue];
            
            % Update the graph
            plot(app.UIAxes, app.Data, '-o');
            drawnow;
            
            % Check if fall is detected
            if newValue > 0.7
                app.StableLabel.Text = 'Fall Detected';
                app.Lamp.Color = 'red';
                sound(app.FallSound, app.FallSoundFs);
            else
                app.StableLabel.Text = 'Stable';
                app.Lamp.Color = 'green';
            end
       end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: StartMonitoringButton
        function StartMonitoringButtonPushed(app, event)
            [app.FallSound, app.FallSoundFs] = audioread('alertsound.wav');
            app.Data = [];
            
            % Create and start the timer
            app.Timer = timer(...
                'ExecutionMode', 'fixedRate', ...   % Run timer repeatedly
                'Period', 1, ...                    % Timer period in seconds
                'TimerFcn', @(~,~)updateGraph(app)); % Timer callback function
            start(app.Timer);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sensor Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Processed Value')
            zlabel(app.UIAxes, 'Z')
            app.FALLDETECTIONLabel = uilabel(app.UIFigure);
            app.FALLDETECTIONLabel.Text = 'FALL DETECTION';
            app.StartMonitoringButton = uibutton(app.UIFigure, 'push');
            app.StartMonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartMonitoringButtonPushed, true);
            app.StartMonitoringButton.Text = 'Start Monitoring';
            app.Lamp = uilamp(app.UIFigure);
            app.StableLabel = uilabel(app.UIFigure);
            app.StableLabel.Text = 'Stable';
            app.ExportFallDataButton = uibutton(app.UIFigure, 'push');
            app.ExportFallDataButton.Text = 'Export Fall Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCSfallDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end